classdef appMaquinaCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI                           matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        MENU                         matlab.ui.container.Tab
        Title                        matlab.ui.control.Label
        Subtitle                     matlab.ui.control.Label
        InsiraumafunoEditFieldLabel  matlab.ui.control.Label
        funcao                       matlab.ui.control.EditField
        aEditFieldLabel              matlab.ui.control.Label
        a_field                      matlab.ui.control.NumericEditField
        bEditFieldLabel              matlab.ui.control.Label
        b_field                      matlab.ui.control.NumericEditField
        nEditFieldLabel              matlab.ui.control.Label
        n_field                      matlab.ui.control.NumericEditField
        CalcularBotao1VR             matlab.ui.control.Button
        MtodosDropDownLabel          matlab.ui.control.Label
        Metodos                      matlab.ui.control.DropDown
        CDI_Botoes                   matlab.ui.container.ButtonGroup
        CalculoIntegral              matlab.ui.control.ToggleButton
        CalculoDiferencial           matlab.ui.control.ToggleButton
        SoluoExataEditFieldLabel     matlab.ui.control.Label
        SolExata                     matlab.ui.control.EditField
        CalcularBotao2VR             matlab.ui.control.Button
        ONE_VR                       matlab.ui.container.Tab
        Label1                       matlab.ui.control.Label
        SubLabel1                    matlab.ui.control.Label
        Tabela1                      matlab.ui.control.Table
        Grelha1                      matlab.ui.control.CheckBox
        Subtitle1                    matlab.ui.control.Label
        FuncaoAtual1                 matlab.ui.control.Label
        Graph1                       matlab.ui.control.UIAxes
        TWO_VR                       matlab.ui.container.Tab
        Label2                       matlab.ui.control.Label
        SubLabel2                    matlab.ui.control.Label
        Tabela2                      matlab.ui.control.Table
        Grelha2                      matlab.ui.control.CheckBox
        Subtitle2                    matlab.ui.control.Label
        FuncaoAtual2                 matlab.ui.control.Label
        Graph2                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            CalcularBotao1VRButtonPushed(app);
        end

        % Button pushed function: CalcularBotao1VR
        function CalcularBotao1VRButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TWO_VR;
            
            syms y(x)
            cla(app.Graph1);
            addpath "Métodos";
            
            botaoSelecionado = app.CDI_Botoes.SelectedObject.Text;
            metodo = app.Metodos.Value;

            switch botaoSelecionado
                case 'Cálculo Diferencial'
                    app.Subtitle1.Text = botaoSelecionado;
                    app.Subtitle2.Text = botaoSelecionado;
                case 'Cálculo Integral'
                    app.Subtitle1.Text = botaoSelecionado;
                    app.Subtitle2.Text = botaoSelecionado;
            end
            
           
            %%%%% Verificação do Input %%%%%
            strF = app.funcao.Value; % Obter o valor no campo da função f
            
            f = @(x) eval(vectorize(strF)); % Definir a nossa função f
            try % Testar se a função inserida é válida
                f(x);
            catch
                errordlg("Introduza uma função em x","Erro");
                return
            end
            
            
            a = app.a_field.Value; % Obter o valor do campo A
            if (not(isscalar(a) && isreal(a))) % O valor A tem de ser real
                errordlg("O número introduzido para a é inválido","Erro");
                return
            end
            
            b = app.b_field.Value; % Obter o valor do campo B
            if (not(isscalar(b) && isreal(b) && b>a)) % O valor B tem de ser real e maior que A
                errordlg("O número introduzido para b é inválido","Erro");
                return
            end
        
            n = app.n_field.Value; % Obter o valor do campo N
            if (not(isscalar(n)))
                errordlg("O número introduzido para n é inválido","Erro");
                return
            end
            
            app.FuncaoAtual1.Text = strcat("Função: ", strF, "    a: ", string(a), "    b: ", string(b), "    Iterações: ", string(n), "    Método: ", metodo);
            
            h = (b-a)/n;
            
            switch botaoSelecionado
                case 'Cálculo Diferencial'
                    st = 0:1:n;
                    t = a:h:b;
                    d = diff(f,x);
                    g = @(x) eval(vectorize(d));
                    fplot(app.Graph1,f,'-b','LineWidth',1);
                    hold(app.Graph1, "on");
                    fplot(app.Graph1,g,'--r','LineWidth',1);
                    hold(app.Graph1, "on");
                    xlim(app.Graph1, [a b]);
                    if(metodo == "D.F. Progressivas (2 pontos)")
                        [t,~,dydx] = DF2Progressivas(f,a,b,h);
                        plot(app.Graph1,t,dydx,'-mo','LineWidth',1);
                        hold(app.Graph1, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErro = derivada - dydx;
                        dfErro = abs(dfErro);
                        legend(app.Graph1,'Função','Derivada','DF2P','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydx.',dfErro.']; % Criação da tabela
                        set(app.Tabela1, 'columnname', {'Iteração','x','y','Derivada','DFP2', 'Erro DFP2'}); % Nomes das colunas da tabela
                        app.Tabela1.Data = tabela;
                    elseif(metodo == "D.F. Regressivas (2 pontos)")
                        [t,~,dydx] = DF2Regressivas(f,a,b,h);
                        plot(app.Graph1,t,dydx,'-mo','LineWidth',1);
                        hold(app.Graph1, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErro = derivada - dydx;
                        dfErro = abs(dfErro);
                        legend(app.Graph1,'Função','Derivada','DF2R','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydx.',dfErro.']; % Criação da tabela
                        set(app.Tabela1, 'columnname', {'Iteração','x','y','Derivada','DFR2','Erro DFR2'}); % Nomes das colunas da tabela
                        app.Tabela1.Data = tabela;
                    elseif(metodo == "D.F. Progressivas (3 pontos)")
                        [t,~,dydx] = DF3Progressivas(f,a,b,h);
                        plot(app.Graph1,t,dydx,'-mo','LineWidth',1);
                        hold(app.Graph1, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErro = derivada - dydx;
                        dfErro = abs(dfErro);
                        legend(app.Graph1,'Função','Derivada','DF3P','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydx.',dfErro.']; % Criação da tabela
                        set(app.Tabela1, 'columnname', {'Iteração','x','y','Derivada','DFP3','Erro DFP3'}); % Nomes das colunas da tabela
                        app.Tabela1.Data = tabela;
                    elseif(metodo == "D.F. Regressivas (3 pontos)")
                        [t,~,dydx] = DF3Regressivas(f,a,b,h);
                        plot(app.Graph1,t,dydx,'-mo','LineWidth',1);
                        hold(app.Graph1, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErro = derivada - dydx;
                        dfErro = abs(dfErro);
                        legend(app.Graph1,'Função','Derivada','DF3R','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydx.',dfErro.']; % Criação da tabela
                        set(app.Tabela1, 'columnname', {'Iteração','x','y','Derivada','DFR3','Erro DFR3'}); % Nomes das colunas da tabela
                        app.Tabela1.Data = tabela;
                    elseif(metodo == "D.F. Centradas (3 pontos)")
                        [t,~,dydx] = DF3Centradas(f,a,b,h);
                        plot(app.Graph1,t,dydx,'-mo','LineWidth',1);
                        hold(app.Graph1, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErro = derivada - dydx;
                        dfErro = abs(dfErro);
                        legend(app.Graph1,'Função','Derivada','DF3C','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydx.',dfErro.']; % Criação da tabela
                        set(app.Tabela1, 'columnname', {'Iteração','x','y','Derivada','DFC3','Erro DFC3'}); % Nomes das colunas da tabela
                        app.Tabela1.Data = tabela;
                    elseif(metodo == "D.F. 2ª Derivada (3 pontos)")
                        [t,~,dydx] = DF3Centradas(f,a,b,h);
                        plot(app.Graph1,t,dydx,'-mo','LineWidth',1);
                        hold(app.Graph1, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErro = derivada - dydx;
                        dfErro = abs(dfErro);
                        legend(app.Graph1,'Função','Derivada','DF3D','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydx.',dfErro.']; % Criação da tabela
                        set(app.Tabela1, 'columnname', {'Iteração','x','y','Derivada','DF3D','Erro DF3D'}); % Nomes das colunas da tabela
                        app.Tabela1.Data = tabela;
                    elseif(metodo == "Todos")
                        [t,~,dydxDF2P] = DF2Progressivas(f,a,b,h);
                        [~,~,dydxDF2R] = DF2Regressivas(f,a,b,h);
                        [~,~,dydxDF3P] = DF3Progressivas(f,a,b,h);
                        [~,~,dydxDF3R] = DF3Regressivas(f,a,b,h);
                        [~,~,dydxDF3C] = DF3Centradas(f,a,b,h);
                        [~,~,dydxDF3D] = DF3Derivada(f,a,b,h);
                        plot(app.Graph1,t,dydxDF2P,'-mo','LineWidth',1);
                        plot(app.Graph1,t,dydxDF2R,'-go','LineWidth',1);
                        plot(app.Graph1,t,dydxDF3P,'-co','LineWidth',1);
                        plot(app.Graph1,t,dydxDF3R,'-ko','LineWidth',1);
                        plot(app.Graph1,t,dydxDF3C,'--m','LineWidth',1);
                        plot(app.Graph1,t,dydxDF3D,'--g','LineWidth',1);
                        hold(app.Graph1, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErroDF2P = derivada - dydxDF2P;
                        dfErroDF2R = derivada - dydxDF2R;
                        dfErroDF3P = derivada - dydxDF3P;
                        dfErroDF3R = derivada - dydxDF3R;
                        dfErroDF3C = derivada - dydxDF3C;
                        dfErroDF3D = derivada - dydxDF3D;
                        dfErroDF2P = abs(dfErroDF2P);
                        dfErroDF2R = abs(dfErroDF2R);
                        dfErroDF3P = abs(dfErroDF3P);
                        dfErroDF3R = abs(dfErroDF3R);
                        dfErroDF3C = abs(dfErroDF3C);
                        dfErroDF3D = abs(dfErroDF3D);
                        legend(app.Graph1,'Função','Derivada','DF2P','DF2R','DF3P','DF3R','DF3C','DF3D','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydxDF2P.',dfErroDF2P.',dydxDF2R.',dfErroDF2R.',dydxDF3P.',dfErroDF3P.',dydxDF3R.',dfErroDF3R.',dydxDF3C.',dfErroDF3C.',dydxDF3D.',dfErroDF3D.']; % Criação da tabela
                        set(app.Tabela1, 'columnname', {'Iteração','x','y','Derivada','DF2P','Erro DF2P','DF2R','Erro DF2R','DF3P','Erro DF3P','DF3R','Erro DF3R','DF3C','Erro DF3C','DF3D','Erro DF3D'}); % Nomes das colunas da tabela
                        app.Tabela1.Data = tabela;
                    end

                case 'Cálculo Integral'
                    st = 0:1:n;
                    t = a:h:b;
                    d = int(f,x);
                    g = @(x) eval(vectorize(d));
                    fplot(app.Graph1,f,'-bo','LineWidth',1);
                    hold(app.Graph1, "on");
                    fplot(app.Graph1,g,'--r','LineWidth',1);
                    hold(app.Graph1, "on");
                    xlim(app.Graph1, [a b]);
                    func = f(t);
                    primitiva = g(t);
                    legend(app.Graph1,'Função','Primitiva','Location','northwest'); % Legenda do gráfico
                    tabela = [st.',t.',func.',primitiva.']; % Criação da tabela
                    set(app.Tabela1, 'columnname', {'Iteração','t','Função','Primitiva'}); % Nomes das colunas da tabela
                    app.Tabela1.Data = tabela;
            end
        end

        % Value changed function: funcao
        function funcaoValueChanged(app, event)
            value = app.funcao.Value;
            botaoSelecionado = app.CDI_Botoes.SelectedObject.Text;
            
            funValor = app.funcao.Value;
            aValor = app.a_field.Value;
            bValor = app.b_field.Value;
            nValor = app.n_field.Value;
            metodoValor = app.Metodos.Value;
            
            syms y(x)
            f = @(x) eval(vectorize(value));
            switch botaoSelecionado
                case 'Cálculo Diferencial'
                    d = diff(f,x);
                    app.SolExata.Value = string(d);
                case 'Cálculo Integral'
                    d = int(f,x);
                    app.SolExata.Value = string(d);
            end
            
            
        end

        % Selection changed function: CDI_Botoes
        function CDI_BotoesSelectionChanged(app, event)
            value = app.funcao.Value;
            selectedButton = app.CDI_Botoes.SelectedObject.Text;
            dropdown = app.Metodos;
            
            syms y(x)
            f = @(x) eval(vectorize(value));
            switch selectedButton
                case 'Cálculo Diferencial'
                    dropdown.Items = {  
                                            'D.F. Progressivas (2 pontos)'
                                            'D.F. Regressivas (2 pontos)'
                                            'D.F. Progressivas (3 pontos)'
                                            'D.F. Regressivas (3 pontos)'
                                            'D.F. Centradas (3 pontos)'
                                            'D.F. 2ª Derivada (3 pontos)'
                                            'Todos'
                                     };
                    
                    d = diff(f,x);
                    app.SolExata.Value = string(d);
                case 'Cálculo Integral'                    
                    dropdown.Items = {  
                                            'Regra dos Trapézios'
                                            'Regra de Simpson'
                                            'Todos'
                                     };
                    
                    d = int(f,x);
                    app.SolExata.Value = string(d);
            end
        end

        % Value changed function: Grelha1
        function Grelha1ValueChanged(app, event)
            value = app.Grelha1.Value;
            switch value
                case 1
                    grid(app.Graph1, "on");
                case 0
                    grid(app.Graph1, "off");
            end
        end

        % Button pushed function: CalcularBotao2VR
        function CalcularBotao2VRButtonPushed(app, event)
            
            app.TabGroup.SelectedTab = app.TWO_VR;
            
            syms y(x)
            cla(app.Graph2);
            addpath "Métodos";
            
            botaoSelecionado = app.CDI_Botoes.SelectedObject.Text;
            metodo = app.Metodos.Value;

            switch botaoSelecionado
                case 'Cálculo Diferencial'
                    app.Subtitle1.Text = botaoSelecionado;
                    app.Subtitle2.Text = botaoSelecionado;
                case 'Cálculo Integral'
                    app.Subtitle1.Text = botaoSelecionado;
                    app.Subtitle2.Text = botaoSelecionado;
            end
            
           
            %%%%% Verificação do Input %%%%%
            strF = app.funcao.Value; % Obter o valor no campo da função f
            
            f = @(x) eval(vectorize(strF)); % Definir a nossa função f
            try % Testar se a função inserida é válida
                f(x);
            catch
                errordlg("Introduza uma função em x","Erro");
                return
            end
            
            
            a = app.a_field.Value; % Obter o valor do campo A
            if (not(isscalar(a) && isreal(a))) % O valor A tem de ser real
                errordlg("O número introduzido para a é inválido","Erro");
                return
            end
            
            b = app.b_field.Value; % Obter o valor do campo B
            if (not(isscalar(b) && isreal(b) && b>a)) % O valor B tem de ser real e maior que A
                errordlg("O número introduzido para b é inválido","Erro");
                return
            end
        
            n = app.n_field.Value; % Obter o valor do campo N
            if (not(isscalar(n)))
                errordlg("O número introduzido para n é inválido","Erro");
                return
            end
            
            app.FuncaoAtual2.Text = strcat("Função: ", strF, "    a: ", string(a), "    b: ", string(b), "    Iterações: ", string(n), "    Método: ", metodo);
            
            h = (b-a)/n;
            
            switch botaoSelecionado
                case 'Cálculo Diferencial'
                    st = 0:1:n;
                    t = a:h:b;
                    d = diff(f,x);
                    g = @(x) eval(vectorize(d));
                    fplot(app.Graph2,f,'-b','LineWidth',1);
                    hold(app.Graph2, "on");
                    fplot(app.Graph2,g,'--r','LineWidth',1);
                    hold(app.Graph2, "on");
                    xlim(app.Graph2, [a b]);
                    if(metodo == "D.F. Progressivas (2 pontos)")
                        [t,~,dydx] = DF2Progressivas(f,a,b,h);
                        plot(app.Graph2,t,dydx,'-mo','LineWidth',1);
                        hold(app.Graph2, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErro = derivada - dydx;
                        dfErro = abs(dfErro);
                        legend(app.Graph2,'Função','Derivada','DF2P','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydx.',dfErro.']; % Criação da tabela
                        set(app.Tabela2, 'columnname', {'Iteração','x','y','Derivada','DFP2', 'Erro DFP2'}); % Nomes das colunas da tabela
                        app.Tabela2.Data = tabela;
                    elseif(metodo == "D.F. Regressivas (2 pontos)")
                        [t,~,dydx] = DF2Regressivas(f,a,b,h);
                        plot(app.Graph2,t,dydx,'-mo','LineWidth',1);
                        hold(app.Graph2, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErro = derivada - dydx;
                        dfErro = abs(dfErro);
                        legend(app.Graph2,'Função','Derivada','DF2R','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydx.',dfErro.']; % Criação da tabela
                        set(app.Tabela2, 'columnname', {'Iteração','x','y','Derivada','DFR2','Erro DFR2'}); % Nomes das colunas da tabela
                        app.Tabela2.Data = tabela;
                    elseif(metodo == "D.F. Progressivas (3 pontos)")
                        [t,~,dydx] = DF3Progressivas(f,a,b,h);
                        plot(app.Graph2,t,dydx,'-mo','LineWidth',1);
                        hold(app.Graph2, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErro = derivada - dydx;
                        dfErro = abs(dfErro);
                        legend(app.Graph2,'Função','Derivada','DF3P','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydx.',dfErro.']; % Criação da tabela
                        set(app.Tabela2, 'columnname', {'Iteração','x','y','Derivada','DFP3','Erro DFP3'}); % Nomes das colunas da tabela
                        app.Tabela2.Data = tabela;
                    elseif(metodo == "D.F. Regressivas (3 pontos)")
                        [t,~,dydx] = DF3Regressivas(f,a,b,h);
                        plot(app.Graph2,t,dydx,'-mo','LineWidth',1);
                        hold(app.Graph2, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErro = derivada - dydx;
                        dfErro = abs(dfErro);
                        legend(app.Graph2,'Função','Derivada','DF3R','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydx.',dfErro.']; % Criação da tabela
                        set(app.Tabela2, 'columnname', {'Iteração','x','y','Derivada','DFR3','Erro DFR3'}); % Nomes das colunas da tabela
                        app.Tabela2.Data = tabela;
                    elseif(metodo == "D.F. Centradas (3 pontos)")
                        [t,~,dydx] = DF3Centradas(f,a,b,h);
                        plot(app.Graph2,t,dydx,'-mo','LineWidth',1);
                        hold(app.Graph2, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErro = derivada - dydx;
                        dfErro = abs(dfErro);
                        legend(app.Graph2,'Função','Derivada','DF3C','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydx.',dfErro.']; % Criação da tabela
                        set(app.Tabela2, 'columnname', {'Iteração','x','y','Derivada','DFC3','Erro DFC3'}); % Nomes das colunas da tabela
                        app.Tabela2.Data = tabela;
                    elseif(metodo == "D.F. 2ª Derivada (3 pontos)")
                        [t,~,dydx] = DF3Centradas(f,a,b,h);
                        plot(app.Graph2,t,dydx,'-mo','LineWidth',1);
                        hold(app.Graph2, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErro = derivada - dydx;
                        dfErro = abs(dfErro);
                        legend(app.Graph2,'Função','Derivada','DF3D','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydx.',dfErro.']; % Criação da tabela
                        set(app.Tabela2, 'columnname', {'Iteração','x','y','Derivada','DF3D','Erro DF3D'}); % Nomes das colunas da tabela
                        app.Tabela2.Data = tabela;
                    elseif(metodo == "Todos")
                        [t,~,dydxDF2P] = DF2Progressivas(f,a,b,h);
                        [~,~,dydxDF2R] = DF2Regressivas(f,a,b,h);
                        [~,~,dydxDF3P] = DF3Progressivas(f,a,b,h);
                        [~,~,dydxDF3R] = DF3Regressivas(f,a,b,h);
                        [~,~,dydxDF3C] = DF3Centradas(f,a,b,h);
                        [~,~,dydxDF3D] = DF3Derivada(f,a,b,h);
                        plot(app.Graph2,t,dydxDF2P,'-mo','LineWidth',1);
                        plot(app.Graph2,t,dydxDF2R,'-go','LineWidth',1);
                        plot(app.Graph2,t,dydxDF3P,'-co','LineWidth',1);
                        plot(app.Graph2,t,dydxDF3R,'-ko','LineWidth',1);
                        plot(app.Graph2,t,dydxDF3C,'--m','LineWidth',1);
                        plot(app.Graph2,t,dydxDF3D,'--g','LineWidth',1);
                        hold(app.Graph2, "on");
                        func = f(t);
                        derivada = g(t);
                        dfErroDF2P = derivada - dydxDF2P;
                        dfErroDF2R = derivada - dydxDF2R;
                        dfErroDF3P = derivada - dydxDF3P;
                        dfErroDF3R = derivada - dydxDF3R;
                        dfErroDF3C = derivada - dydxDF3C;
                        dfErroDF3D = derivada - dydxDF3D;
                        dfErroDF2P = abs(dfErroDF2P);
                        dfErroDF2R = abs(dfErroDF2R);
                        dfErroDF3P = abs(dfErroDF3P);
                        dfErroDF3R = abs(dfErroDF3R);
                        dfErroDF3C = abs(dfErroDF3C);
                        dfErroDF3D = abs(dfErroDF3D);
                        legend(app.Graph2,'Função','Derivada','DF2P','DF2R','DF3P','DF3R','DF3C','DF3D','Location','northwest'); % Legenda do gráfico
                        tabela = [st.',t.',func.',derivada.',dydxDF2P.',dfErroDF2P.',dydxDF2R.',dfErroDF2R.',dydxDF3P.',dfErroDF3P.',dydxDF3R.',dfErroDF3R.',dydxDF3C.',dfErroDF3C.',dydxDF3D.',dfErroDF3D.']; % Criação da tabela
                        set(app.Tabela2, 'columnname', {'Iteração','x','y','Derivada','DF2P','Erro DF2P','DF2R','Erro DF2R','DF3P','Erro DF3P','DF3R','Erro DF3R','DF3C','Erro DF3C','DF3D','Erro DF3D'}); % Nomes das colunas da tabela
                        app.Tabela2.Data = tabela;
                    end

                case 'Cálculo Integral'
                    st = 0:1:n;
                    t = a:h:b;
                    d = int(f,x);
                    g = @(x) eval(vectorize(d));
                    fplot(app.Graph2,f,'-bo','LineWidth',1);
                    hold(app.Graph2, "on");
                    fplot(app.Graph2,g,'--r','LineWidth',1);
                    hold(app.Graph2, "on");
                    xlim(app.Graph2, [a b]);
                    func = f(t);
                    primitiva = g(t);
                    legend(app.Graph2,'Função','Primitiva','Location','northwest'); % Legenda do gráfico
                    tabela = [st.',t.',func.',primitiva.']; % Criação da tabela
                    set(app.Tabela2, 'columnname', {'Iteração','t','Função','Primitiva'}); % Nomes das colunas da tabela
                    app.Tabela2.Data = tabela;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure('Visible', 'off');
            app.UI.Name = 'Máquina CDI';
            app.TabGroup = uitabgroup(app.UI);
            app.MENU = uitab(app.TabGroup);
            app.MENU.Title = 'Menu';
            app.Title = uilabel(app.MENU);
            app.Title.Text = 'Máquina';
            app.Subtitle = uilabel(app.MENU);
            app.Subtitle.Text = 'Cáculo Diferencial e Integral';
            app.InsiraumafunoEditFieldLabel = uilabel(app.MENU);
            app.InsiraumafunoEditFieldLabel.Text = 'Insira uma função';
            app.funcao = uieditfield(app.MENU, 'text');
            app.funcao.ValueChangedFcn = createCallbackFcn(app, @funcaoValueChanged, true);
            app.funcao.Value = 'sin(x)';
            app.aEditFieldLabel = uilabel(app.MENU);
            app.aEditFieldLabel.Text = 'a';
            app.a_field = uieditfield(app.MENU, 'numeric');
            app.bEditFieldLabel = uilabel(app.MENU);
            app.bEditFieldLabel.Text = 'b';
            app.b_field = uieditfield(app.MENU, 'numeric');
            app.b_field.Value = 6.286;
            app.nEditFieldLabel = uilabel(app.MENU);
            app.nEditFieldLabel.Text = 'n';
            app.n_field = uieditfield(app.MENU, 'numeric');
            app.n_field.Value = 20;
            app.CalcularBotao1VR = uibutton(app.MENU, 'push');
            app.CalcularBotao1VR.ButtonPushedFcn = createCallbackFcn(app, @CalcularBotao1VRButtonPushed, true);
            app.CalcularBotao1VR.Text = 'Calcular (1 Variável Real)';
            app.MtodosDropDownLabel = uilabel(app.MENU);
            app.MtodosDropDownLabel.Text = 'Métodos';
            app.Metodos = uidropdown(app.MENU);
            app.Metodos.Items = {'D.F. Progressivas (2 pontos)', 'D.F. Regressivas (2 pontos)', 'D.F. Progressivas (3 pontos)', 'D.F. Regressivas (3 pontos)', 'D.F. Centradas (3 pontos)', 'D.F. 2ª Derivada (3 pontos)', 'Todos'};
            app.Metodos.Value = 'D.F. Progressivas (2 pontos)';
            app.CDI_Botoes = uibuttongroup(app.MENU);
            app.CDI_Botoes.SelectionChangedFcn = createCallbackFcn(app, @CDI_BotoesSelectionChanged, true);
            app.CalculoIntegral = uitogglebutton(app.CDI_Botoes);
            app.CalculoIntegral.Text = 'Cálculo Diferencial';
            app.CalculoIntegral.Value = true;
            app.CalculoDiferencial = uitogglebutton(app.CDI_Botoes);
            app.CalculoDiferencial.Text = 'Cálculo Integral';
            app.SoluoExataEditFieldLabel = uilabel(app.MENU);
            app.SoluoExataEditFieldLabel.Text = 'Solução Exata';
            app.SolExata = uieditfield(app.MENU, 'text');
            app.SolExata.Editable = 'off';
            app.SolExata.Value = 'cos(x)';
            app.CalcularBotao2VR = uibutton(app.MENU, 'push');
            app.CalcularBotao2VR.ButtonPushedFcn = createCallbackFcn(app, @CalcularBotao2VRButtonPushed, true);
            app.CalcularBotao2VR.Text = 'Calcular (2 Variáveis Reais)';
            app.ONE_VR = uitab(app.TabGroup);
            app.ONE_VR.Title = 'Função real de 1 variável real: y = f(x)';
            app.Label1 = uilabel(app.ONE_VR);
            app.Label1.Text = 'Função real de 1 variável real';
            app.SubLabel1 = uilabel(app.ONE_VR);
            app.SubLabel1.Text = 'y = f(x)';
            app.Tabela1 = uitable(app.ONE_VR);
            app.Tabela1.ColumnName = {''; ''; ''; ''};
            app.Tabela1.RowName = {};
            app.Grelha1 = uicheckbox(app.ONE_VR);
            app.Grelha1.ValueChangedFcn = createCallbackFcn(app, @Grelha1ValueChanged, true);
            app.Grelha1.Text = 'Grelha';
            app.Subtitle1 = uilabel(app.ONE_VR);
            app.Subtitle1.Text = 'Cálculo Diferencial';
            app.FuncaoAtual1 = uilabel(app.ONE_VR);
            app.FuncaoAtual1.Text = 'Função: sin(x)    a: 0    b: 6.286    Iterações: 20    Método: D.F. Progressivas (2 pontos)';
            app.Graph1 = uiaxes(app.ONE_VR);
            zlabel(app.Graph1, 'Z')
            app.TWO_VR = uitab(app.TabGroup);
            app.TWO_VR.Title = 'Função real de 2 variáveis reais: z = f(x,y)';
            app.Label2 = uilabel(app.TWO_VR);
            app.Label2.Text = 'Função real de 2 variáveis reais';
            app.SubLabel2 = uilabel(app.TWO_VR);
            app.SubLabel2.Text = 'z = f(x,y)';
            app.Tabela2 = uitable(app.TWO_VR);
            app.Tabela2.ColumnName = {''; ''; ''; ''};
            app.Tabela2.RowName = {};
            app.Grelha2 = uicheckbox(app.TWO_VR);
            app.Grelha2.Text = 'Grelha';
            app.Subtitle2 = uilabel(app.TWO_VR);
            app.Subtitle2.Text = 'Cálculo Diferencial';
            app.FuncaoAtual2 = uilabel(app.TWO_VR);
            app.FuncaoAtual2.Text = 'Função: sin(x)    a: 0    b: 6.286    Iterações: 20    Método: D.F. Progressivas (2 pontos)';
            app.Graph2 = uiaxes(app.TWO_VR);
            zlabel(app.Graph2, 'Z')
            % Show the figure after all components are created
            app.UI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaCDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end